classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        FirstFilterDropDownLabel        matlab.ui.control.Label
        FirstFilterDropDown             matlab.ui.control.DropDown
        SecondFilterDropDownLabel       matlab.ui.control.Label
        SecondFilterDropDown            matlab.ui.control.DropDown
        Button                          matlab.ui.control.StateButton
        ListBoxLabel                    matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        SimilarityThresholdEditField_2Label  matlab.ui.control.Label
        SimilarityThresholdEditField_2  matlab.ui.control.NumericEditField
        SimilarityThresholdEditField_3Label  matlab.ui.control.Label
        SimilarityThresholdEditField_3  matlab.ui.control.NumericEditField
        Button2                         matlab.ui.control.Button
    end


    methods (Access = private)

        % Callback function
        function uploadQueryImage(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.gif;*.bmp', 'All Image Files (*.jpg, *.png, *.gif, *.bmp)'; ...
                '*.*',                   'All Files (*.*)'}, ...
                'Pick an image file', 'MultiSelect', 'on');
            image = imread([pathname,filename]);
     
            imshow(image, 'Parent', app.UIAxes);
            
        end

        % Callback function
        function SimilarityDropDownValueChanged(app, event)
app.SimilarityDropDown            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.FirstFilterDropDownLabel = uilabel(app.UIFigure);
            app.FirstFilterDropDownLabel.Text = 'First Filter';
            app.FirstFilterDropDown = uidropdown(app.UIFigure);
            app.FirstFilterDropDown.Items = {'HSV Color Histogram', 'Gabor Wavelets (mean)', 'Gabor Wavelets (std dev)'};
            app.FirstFilterDropDown.Value = 'HSV Color Histogram';
            app.SecondFilterDropDownLabel = uilabel(app.UIFigure);
            app.SecondFilterDropDownLabel.Text = 'Second Filter';
            app.SecondFilterDropDown = uidropdown(app.UIFigure);
            app.SecondFilterDropDown.Items = {'HSV Color Histogram', 'Gabor Wavelets (mean)', 'Gabor Wavelets (std dev)'};
            app.SecondFilterDropDown.Value = 'HSV Color Histogram';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.Text = 'Button';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.SimilarityThresholdEditField_2Label = uilabel(app.UIFigure);
            app.SimilarityThresholdEditField_2Label.Text = 'Similarity Threshold';
            app.SimilarityThresholdEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SimilarityThresholdEditField_3Label = uilabel(app.UIFigure);
            app.SimilarityThresholdEditField_3Label.Text = 'Similarity Threshold';
            app.SimilarityThresholdEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.Text = 'Button2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end